classdef M_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        approvechangesButton         matlab.ui.control.Button
        NumberofDOFisEditField       matlab.ui.control.NumericEditField
        NumberofDOFisEditFieldLabel  matlab.ui.control.Label
        YoucaneitherentertothetextareaorpresstheReadfromfilebuttonLabel  matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        INPUTMASSMATRIXLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp % Description
        n %number of DOFS
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, n)
            app.callingapp=mainapp;
            app.n=n;
            app.NumberofDOFisEditField.Value=app.n;

            %Write zeros for ease
            text=cell(app.n,1);
            for i=1:app.n
                dum=sprintf('%.0f,',zeros(1,app.n-i+1)); dum=dum(1:end-1);
                text{i}=dum;
            end
            app.TextArea.Value=text;
        end

        % Button pushed function: approvechangesButton
        function approvechangesButtonPushed(app, event)
            text=app.TextArea.Value;
            n=size(text,1);
            vect=[];
            for i=1:n
                vect=[vect,str2num(cell2mat(text(i)))];
            end
            M=vec2sym_mat(vect);
            app.callingapp.M=M;
            app.callingapp.FButton.Enable='on';
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.INPUTMASSMATRIXLabel = uilabel(app.UIFigure);
            app.INPUTMASSMATRIXLabel.Text = ' INPUT MASS MATRIX';
            app.TextArea = uitextarea(app.UIFigure);
            app.YoucaneitherentertothetextareaorpresstheReadfromfilebuttonLabel = uilabel(app.UIFigure);
            app.YoucaneitherentertothetextareaorpresstheReadfromfilebuttonLabel.Interpreter = 'html';
            app.YoucaneitherentertothetextareaorpresstheReadfromfilebuttonLabel.Text = {'You should enter only the upper diagonal part of the mass matrix. An example is as follows: .<br>'; '1,2,3,4<br>'; '5,6,7<br>'; '8,9<br>'; '10'};
            app.NumberofDOFisEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofDOFisEditFieldLabel.Text = 'Number of DOF is';
            app.NumberofDOFisEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDOFisEditField.Editable = 'off';
            app.NumberofDOFisEditField.Value = 4;
            app.approvechangesButton = uibutton(app.UIFigure, 'push');
            app.approvechangesButton.ButtonPushedFcn = createCallbackFcn(app, @approvechangesButtonPushed, true);
            app.approvechangesButton.Text = 'approve changes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = M_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end