classdef NL_editor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NONLINEARITYEDITORLabel        matlab.ui.control.Label
        SelectnonlinearitytypeLabel    matlab.ui.control.Label
        applyandcloseButton            matlab.ui.control.Button
        DeleteallButton                matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        DeleteithButton                matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        ParametersEditField            matlab.ui.control.EditField
        ParametersEditFieldLabel       matlab.ui.control.Label
        jEditField                     matlab.ui.control.NumericEditField
        jEditFieldLabel                matlab.ui.control.Label
        iEditField                     matlab.ui.control.NumericEditField
        iEditFieldLabel                matlab.ui.control.Label
        NonlinearitytypeDropDown       matlab.ui.control.DropDown
        NonlinearitytypeDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        type % nonlinearity type
        i %first index
        j %second index
        par %parameters
        NL_data %nonlinearity data
        callingapp %mainapp
    end
    
    methods (Access = private)
        
        function  write_to_textarea(app,NL_data)
            if size(NL_data,1)~=0
                nl_size=size(NL_data,1);
                text=cell(nl_size,1);
                for in=1:nl_size
                    dum=NL_data(in,:);
                    name=app.NonlinearitytypeDropDown.Items(dum(3)); name=name{1};
                    text{in}=sprintf('%d) %s i=%.0f j=%.0f par=[%.5f, %.5f, %.5f]'...
                    ,in,name,dum(1),dum(2),dum(4),dum(5),dum(6));
                end
                app.TextArea.Value=text;
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, NL_data)
            app.callingapp=mainapp;
            app.NL_data=NL_data;
            app.type=1;
            app.i=app.iEditField.Value;
            app.j=app.jEditField.Value;
            app.par=str2num(app.ParametersEditField.Value);
            app.write_to_textarea(app.NL_data);


        end

        % Value changed function: NonlinearitytypeDropDown
        function NonlinearitytypeDropDownValueChanged(app, event)
            value= app.NonlinearitytypeDropDown.Value;
            switch value
                case 'Cubic stiffness'
                    app.type=1;
                case 'Gap'
                    app.type=2;
                case 'Piecewise linear stiffness'
                    app.type=3;
                case 'Dry friction'
                    app.type=4;
                case 'Cubic damping'
                    app.type=5;
                case 'Square root stiffness'
                    app.type=6;
            end
        end

        % Callback function
        function displayButtonPushed(app, event)
            display(app.type)
            display(app.i)
            display(app.j)
            display(app.par)
            
        end

        % Value changed function: iEditField
        function iEditFieldValueChanged(app, event)
            value = app.iEditField.Value;
            app.i=value;
        end

        % Value changed function: jEditField
        function jEditFieldValueChanged(app, event)
            value = app.jEditField.Value;
            app.j=value;
        end

        % Value changed function: ParametersEditField
        function ParametersEditFieldValueChanged(app, event)
            value = app.ParametersEditField.Value;
            app.par=str2num(value);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.NL_data=[app.NL_data;[app.i,app.j,app.type,app.par]];
            app.write_to_textarea(app.NL_data);


        end

        % Button pushed function: DeleteithButton
        function DeleteithButtonPushed(app, event)
            app.NL_data(app.EditField.Value,:)=[];
            app.write_to_textarea(app.NL_data);
        end

        % Button pushed function: DeleteallButton
        function DeleteallButtonPushed(app, event)
            app.NL_data=[];
            app.TextArea.Value='';
        end

        % Button pushed function: applyandcloseButton
        function applyandcloseButtonPushed(app, event)
            app.callingapp.NL_data=app.NL_data;
            app.callingapp.ProceedButton_3.Enable='on';
            app.callingapp.writetofileButton_2.Enable='on';
            app.callingapp.SolveButton.Enable='on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NonlinearitytypeDropDownLabel = uilabel(app.UIFigure);
            app.NonlinearitytypeDropDownLabel.Text = 'Nonlinearity type';
            app.NonlinearitytypeDropDown = uidropdown(app.UIFigure);
            app.NonlinearitytypeDropDown.Items = {'Cubic stiffness', 'Gap', 'Piecewise linear stiffness', 'Dry friction', 'Cubic damping', 'Square root stiffness'};
            app.NonlinearitytypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NonlinearitytypeDropDownValueChanged, true);
            app.NonlinearitytypeDropDown.Value = 'Cubic stiffness';
            app.iEditFieldLabel = uilabel(app.UIFigure);
            app.iEditFieldLabel.Text = 'i';
            app.iEditField = uieditfield(app.UIFigure, 'numeric');
            app.iEditField.ValueChangedFcn = createCallbackFcn(app, @iEditFieldValueChanged, true);
            app.iEditField.Value = 1;
            app.jEditFieldLabel = uilabel(app.UIFigure);
            app.jEditFieldLabel.Text = 'j';
            app.jEditField = uieditfield(app.UIFigure, 'numeric');
            app.jEditField.ValueChangedFcn = createCallbackFcn(app, @jEditFieldValueChanged, true);
            app.jEditField.Value = 1;
            app.ParametersEditFieldLabel = uilabel(app.UIFigure);
            app.ParametersEditFieldLabel.Text = 'Parameters';
            app.ParametersEditField = uieditfield(app.UIFigure, 'text');
            app.ParametersEditField.ValueChangedFcn = createCallbackFcn(app, @ParametersEditFieldValueChanged, true);
            app.ParametersEditField.Value = '0,0,0';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteithButton = uibutton(app.UIFigure, 'push');
            app.DeleteithButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteithButtonPushed, true);
            app.DeleteithButton.Text = 'Delete ith';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 1;
            app.DeleteallButton = uibutton(app.UIFigure, 'push');
            app.DeleteallButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteallButtonPushed, true);
            app.DeleteallButton.Text = 'Delete all';
            app.applyandcloseButton = uibutton(app.UIFigure, 'push');
            app.applyandcloseButton.ButtonPushedFcn = createCallbackFcn(app, @applyandcloseButtonPushed, true);
            app.applyandcloseButton.Text = 'apply and close';
            app.SelectnonlinearitytypeLabel = uilabel(app.UIFigure);
            app.SelectnonlinearitytypeLabel.Interpreter = 'html';
            app.SelectnonlinearitytypeLabel.Text = '- Select nonlinearity type.<br>- Enter the i and j coordinates.<br>- Enter the parameters. Parameter array length should be 3. If the selected nonlinearity have less than 3 parameters, leave the remaining fields as 0.<br>- Push the "add" button to add nonlinearities.<br>- In order to delete a i<sup>th </sup>nonlinearity, use "delete ith" button.<br>- Push "apply and close" button when you are finished.';
            app.NONLINEARITYEDITORLabel = uilabel(app.UIFigure);
            app.NONLINEARITYEDITORLabel.Text = 'NONLINEARITY EDITOR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NL_editor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end