classdef post_processor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        yaxisLabel                matlab.ui.control.Label
        ButtonGroup_4             matlab.ui.container.ButtonGroup
        LinearButton_2            matlab.ui.control.RadioButton
        LogButton_2               matlab.ui.control.RadioButton
        xaxisLabel                matlab.ui.control.Label
        ForceValue                matlab.ui.control.NumericEditField
        ForceValuefornormalizationEditFieldLabel  matlab.ui.control.Label
        ButtonGroup_3             matlab.ui.container.ButtonGroup
        LinearButton              matlab.ui.control.RadioButton
        LogButton                 matlab.ui.control.RadioButton
        ClearButton               matlab.ui.control.Button
        PlotButton                matlab.ui.control.Button
        NormalizationButtonGroup  matlab.ui.container.ButtonGroup
        NormalizationButton       matlab.ui.control.RadioButton
        NonormalizationButton     matlab.ui.control.RadioButton
        ChoosedisplacementcoordinateEditField  matlab.ui.control.NumericEditField
        ChoosedisplacementcoordinateEditFieldLabel  matlab.ui.control.Label
        ButtonGroup               matlab.ui.container.ButtonGroup
        Result2Button_2           matlab.ui.control.RadioButton
        LastRunButton             matlab.ui.control.RadioButton
        LoadResultsButton         matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp % Description
        Res1 %Results coming from the main app
        Res2 %2nd Results, which can be imported as a file
        Res % Currently selected result
        xcoord % coordinate of the node to be plotted
        norm_flag % 0 no normalization, 1 normalization
        norm_val % divide response by this value


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Res1)
            app.callingapp=mainapp;
            app.Res1=Res1;
            app.xcoord=app.ChoosedisplacementcoordinateEditField.Value;
            app.Res=app.Res1;
            app.norm_flag=0;
            app.norm_val=app.ForceValue.Value;
            app.UIAxes.XScale="log";
            app.UIAxes.YScale="log";

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.LastRunButton.Value==1
                app.Res=app.Res1;
            else
                app.Res=app.Res2;
            end
            
        end

        % Button pushed function: LoadResultsButton
        function LoadResultsButtonPushed(app, event)
            app.Result2Button_2.Enable='on';
            [file,path]=uigetfile();
            file_name=[path,file];
            app.Res2=readmatrix(file_name);

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            n=(size(app.Res,1)-1)/2; %number of DOFS
            i=app.xcoord; %node coordinate
            x=sqrt(app.Res(i,:).^2+app.Res(n+i,:).^2);
            w=app.Res(end,:);

            if app.norm_flag==1
                x=x/app.norm_val;
            end

            hold(app.UIAxes,'on');
            plot(app.UIAxes,w,x);

%             if app.plottype==1
%                 loglog(app.UIAxes,w,x);
%             else
%                 plot(app.UIAxes,w,x);
%             end
%             hold(app.UIAxes,"on");


        end

        % Callback function
        function displayButtonPushed(app, event)
            display(app.Res)
            display(app.xcoord)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Value changed function: ChoosedisplacementcoordinateEditField
        function ChoosedisplacementcoordinateEditFieldValueChanged(app, event)
            value = app.ChoosedisplacementcoordinateEditField.Value;
            app.xcoord=value;
        end

        % Selection changed function: NormalizationButtonGroup
        function NormalizationButtonGroupSelectionChanged(app, event)
            if app.NonormalizationButton.Value==1
                app.norm_flag=0;
                app.ForceValue.Enable='off';
            else
                app.norm_flag=1;
                app.ForceValue.Enable="on";
            end
        end

        % Value changed function: ForceValue
        function ForceValueValueChanged(app, event)
            value = app.ForceValue.Value;
            app.norm_val=value;
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
           hold(app.UIAxes,'off');
            if app.LogButton.Value==1
                app.UIAxes.XScale="log";
            else
                app.UIAxes.XScale="linear";
            end
            hold(app.UIAxes,'on');
        end

        % Selection changed function: ButtonGroup_4
        function ButtonGroup_4SelectionChanged(app, event)

            hold(app.UIAxes,'off');
            if app.LogButton_2.Value==1
                app.UIAxes.YScale="log";
            else
                app.UIAxes.YScale="linear";
            end
            hold(app.UIAxes,'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FRF')
            xlabel(app.UIAxes, 'Frequency (rad/s)')
            ylabel(app.UIAxes, 'Displacement')
            zlabel(app.UIAxes, 'Z')
            app.LoadResultsButton = uibutton(app.UIFigure, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultsButtonPushed, true);
            app.LoadResultsButton.Text = 'Load Results';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.LastRunButton = uiradiobutton(app.ButtonGroup);
            app.LastRunButton.Text = 'Last Run';
            app.LastRunButton.Value = true;
            app.Result2Button_2 = uiradiobutton(app.ButtonGroup);
            app.Result2Button_2.Enable = 'off';
            app.Result2Button_2.Text = 'Result 2';
            app.ChoosedisplacementcoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.ChoosedisplacementcoordinateEditFieldLabel.Text = 'Choose displacement coordinate';
            app.ChoosedisplacementcoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChoosedisplacementcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @ChoosedisplacementcoordinateEditFieldValueChanged, true);
            app.ChoosedisplacementcoordinateEditField.Value = 1;
            app.NormalizationButtonGroup = uibuttongroup(app.UIFigure);
            app.NormalizationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormalizationButtonGroupSelectionChanged, true);
            app.NormalizationButtonGroup.Title = 'Normalization';
            app.NonormalizationButton = uiradiobutton(app.NormalizationButtonGroup);
            app.NonormalizationButton.Text = 'No normalization';
            app.NonormalizationButton.Value = true;
            app.NormalizationButton = uiradiobutton(app.NormalizationButtonGroup);
            app.NormalizationButton.Text = 'Normalization';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ButtonGroup_3 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.LogButton = uiradiobutton(app.ButtonGroup_3);
            app.LogButton.Text = 'Log';
            app.LogButton.Value = true;
            app.LinearButton = uiradiobutton(app.ButtonGroup_3);
            app.LinearButton.Text = 'Linear';
            app.ForceValuefornormalizationEditFieldLabel = uilabel(app.UIFigure);
            app.ForceValuefornormalizationEditFieldLabel.Text = 'Force Value for normalization';
            app.ForceValue = uieditfield(app.UIFigure, 'numeric');
            app.ForceValue.ValueChangedFcn = createCallbackFcn(app, @ForceValueValueChanged, true);
            app.ForceValue.Enable = 'off';
            app.ForceValue.Value = 1;
            app.xaxisLabel = uilabel(app.UIFigure);
            app.xaxisLabel.Text = 'x axis';
            app.ButtonGroup_4 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_4SelectionChanged, true);
            app.LogButton_2 = uiradiobutton(app.ButtonGroup_4);
            app.LogButton_2.Text = 'Log';
            app.LogButton_2.Value = true;
            app.LinearButton_2 = uiradiobutton(app.ButtonGroup_4);
            app.LinearButton_2.Text = 'Linear';
            app.yaxisLabel = uilabel(app.UIFigure);
            app.yaxisLabel.Text = 'y axis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = post_processor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end