classdef me579 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        filenameEditField_3            matlab.ui.control.EditField
        filenameEditField_3Label       matlab.ui.control.Label
        WriteResultstofileButton       matlab.ui.control.Button
        GotoPostProcessorButton        matlab.ui.control.Button
        SolutionParametersPanel        matlab.ui.container.Panel
        UpperFrequencyradsEditField    matlab.ui.control.NumericEditField
        UpperFrequencyradsEditFieldLabel  matlab.ui.control.Label
        LowerFrequencyradsEditField    matlab.ui.control.NumericEditField
        LowerFrequencyradsEditFieldLabel  matlab.ui.control.Label
        MaximumRelativeErrorEditField  matlab.ui.control.NumericEditField
        MaximumRelativeErrorEditFieldLabel  matlab.ui.control.Label
        nsubmaxEditField               matlab.ui.control.NumericEditField
        nsubmaxEditFieldLabel          matlab.ui.control.Label
        nsuboptEditField               matlab.ui.control.NumericEditField
        nsuboptEditFieldLabel          matlab.ui.control.Label
        SolveButton                    matlab.ui.control.Button
        ssubmaxEditField               matlab.ui.control.NumericEditField
        ssubmaxEditFieldLabel          matlab.ui.control.Label
        ssubminEditField               matlab.ui.control.NumericEditField
        ssubminEditFieldLabel          matlab.ui.control.Label
        AddNonlinearitiesPanel         matlab.ui.container.Panel
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        radiobutton4                   matlab.ui.control.RadioButton
        radiobutton3                   matlab.ui.control.RadioButton
        OpeneditorButton               matlab.ui.control.Button
        SelectfileButton_2             matlab.ui.control.Button
        filenameEditField_2            matlab.ui.control.EditField
        filenameEditField_2Label       matlab.ui.control.Label
        writetofileButton_2            matlab.ui.control.Button
        ProceedButton_3                matlab.ui.control.Button
        ModalAnalysisPanel             matlab.ui.container.Panel
        ProceedButton_2                matlab.ui.control.Button
        CalculateButton                matlab.ui.control.Button
        modestokeepE_F                 matlab.ui.control.NumericEditField
        NumberofmodestokeepEditFieldLabel  matlab.ui.control.Label
        wn_s_T_A                       matlab.ui.control.TextArea
        NaturalFrequenciesTextAreaLabel  matlab.ui.control.Label
        modestocalculateE_F            matlab.ui.control.NumericEditField
        NumberofmodestocalculateEditFieldLabel  matlab.ui.control.Label
        displayButton                  matlab.ui.control.Button
        LinearSystemParametersPanel    matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        FButton                        matlab.ui.control.Button
        CButton                        matlab.ui.control.Button
        MButton                        matlab.ui.control.Button
        KButton                        matlab.ui.control.Button
        DOFEditField                   matlab.ui.control.NumericEditField
        DOFEditFieldLabel              matlab.ui.control.Label
        LossFactorEditField            matlab.ui.control.NumericEditField
        LossFactorEditFieldLabel       matlab.ui.control.Label
        ProceedButton                  matlab.ui.control.Button
        SelectfileButton               matlab.ui.control.Button
        filenameEditField              matlab.ui.control.EditField
        filenameEditFieldLabel         matlab.ui.control.Label
        writetofileButton              matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        radiobutton2                   matlab.ui.control.RadioButton
        radiobutton1                   matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        n % Description
        K % Description
        M % Description
        C % Description
        gama % Description % Description
        f % forcing vector of size 2n
        m %number of modes to calculate
        mk % number of modes to keep
        Phi % Mode shape matrix
        Phi_s% Enlarged modeshape matrix of size 2n,2mk
        D % Matrix of natural frequencies squared
        NL_data % Nonlinearity data
        smin %minimum step size
        smax %maximum stepsize
        n_opt %optimum number of iterations
        n_max %maximum number of iterations
        max_err %maximum relative error to stop iterations
        w_low %lower frequency bound
        w_up %upper frequency bound
        Res %Results matrix
        Res_conv %Results converted from modal to actual coordinates
    end
    
    properties (Access = private)
        post_processor % post processor app
        K_app %set stiffness matrix
        M_app %set mass matrix
        C_app %set damping matrix
        F_app %set forcing vector
        NL_editor %NL editor app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Determine where your m-file's folder is.
            folder = fileparts(which('me579.mlapp')); 
            addpath(genpath(folder));
            app.gama=app.LossFactorEditField.Value;
            app.m=app.modestocalculateE_F.Value;
            app.mk=app.modestokeepE_F.Value;
            app.smin=app.ssubminEditField.Value;
            app.smax=app.ssubmaxEditField.Value;
            app.n_opt=app.nsuboptEditField.Value;
            app.n_max=app.nsubmaxEditField.Value;
            app.max_err=app.MaximumRelativeErrorEditField.Value;
            app.w_low=app.LowerFrequencyradsEditField.Value;
            app.w_up=app.UpperFrequencyradsEditField.Value;
            app.n=app.DOFEditField.Value;
            
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            if app.radiobutton1.Value==1
                app.Panel.Enable='off';
                app.SelectfileButton.Enable='on';
            else
                app.Panel.Enable='on';
                app.SelectfileButton.Enable='off';
            end
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [app.n,app.K,app.M,app.C,app.gama,app.f]=read_linear_data();
            app.DOFEditField.Value=app.n;
            app.ProceedButton.Enable='on';
            app.writetofileButton.Enable='on';
            app.MButton.Enable='on';
            app.FButton.Enable='on';
        end

        % Button pushed function: displayButton
        function displayButtonPushed(app, event)

            display(app.NL_data);
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            app.ModalAnalysisPanel.Enable='on';
        end

        % Value changed function: modestocalculateE_F
        function modestocalculateE_FValueChanged(app, event)
            app.m = app.modestocalculateE_F.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            [app.Phi,app.D]=modal(app.K,app.M,app.m);
            app.wn_s_T_A.Value=cellstr(num2str(diag(sqrt(app.D))));
            app.ProceedButton_2.Enable='on';
        end

        % Value changed function: modestokeepE_F
        function modestokeepE_FValueChanged(app, event)
            app.mk = app.modestokeepE_F.Value;
        end

        % Button pushed function: ProceedButton_2
        function ProceedButton_2Pushed(app, event)
            app.Phi=app.Phi(:,1:app.mk);
            app.D=app.D(1:app.mk,1:app.mk);
            app.AddNonlinearitiesPanel.Enable='on';
            app.NL_data=[];
            app.ProceedButton_3.Enable="on";
        end

        % Button pushed function: SelectfileButton_2
        function SelectfileButton_2Pushed(app, event)
            [file,path]=uigetfile();
            file_name=[path,file];
            text=fileread(file_name);
            app.NL_data=str2num(text);
            app.ProceedButton_3.Enable='on';
            app.writetofileButton_2.Enable='on';
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            if app.radiobutton3.Value==1
                app.SelectfileButton_2.Enable='on';
                app.OpeneditorButton.Enable='off';
            else
                app.SelectfileButton_2.Enable='off';
                app.OpeneditorButton.Enable='on';
            end

        end

        % Button pushed function: ProceedButton_3
        function ProceedButton_3Pushed(app, event)
            app.SolveButton.Enable='on';
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            
            D=app.D; mk=app.mk; gama=app.gama;
            Cr=app.Phi'*app.C*app.Phi;
            Phi_s=[app.Phi,zeros(app.n,mk);zeros(app.n,mk),app.Phi]; %Phi_star
            app.Phi_s=Phi_s; % to keep it in memory for later use
            f=app.f;

            L=@(w) [(D-w^2*eye(mk,mk)),-(gama*D+w*Cr);(gama*D+w*Cr),(D-w^2*eye(mk,mk))];
            delR_delw=@(a,w)[-2*w*eye(mk,mk),-Cr;Cr,-2*w*eye(mk,mk)]*a;
            R_a=@(a,w) L(w)*a+Phi_s'*NL_calc(app.n,app.NL_data,Phi_s*a,w)-Phi_s'*f;
            
            app.Res=zeros(2*app.mk+1,1); %initiate results matrix
            w=app.w_low;
            a=zeros(2*app.mk,1); %Very first guess for x
            a=N_R(app.n_max,a,w,R_a,1e-6); %Newton Raphson
            app.Res(1:end-1,1)=a; app.Res(end,1)=w;

            %initial guess for the first step of arc length continuation method, k=2
            w=w+0.01;
            a=N_R(app.n_max,a,w,R_a,1e-6);
            q=[a;w];

            % Arc Length Continuation Method Begins
            k=2; eps=1;
            del_s=app.smax;
            while and(q(end)<=app.w_up,q(end)>=app.w_low)
                %adjust the step size
                del_s=adapt_s(eps,del_s,app.smin,app.smax);
                %corrector step
                q_previous=app.Res(:,k-1);
                [q,ite]=corrector(q,q_previous,app.n_max,R_a,delR_delw,del_s,1e-6);
                eps=app.n_opt/ite;
                [ite,q(end)]
                app.Res(:,k)=q; %write the results
                %predictor step, initial guess for the k+1th iteration
                q=predictor2(q,q_previous,1e-6,del_s,R_a,delR_delw);
                k=k+1;  
            end


        end

        % Value changed function: ssubminEditField
        function ssubminEditFieldValueChanged(app, event)
            app.smin = app.ssubminEditField.Value;            
        end

        % Value changed function: ssubmaxEditField
        function ssubmaxEditFieldValueChanged(app, event)
            app.smax = app.ssubmaxEditField.Value;            
        end

        % Value changed function: nsuboptEditField
        function nsuboptEditFieldValueChanged(app, event)
            app.n_opt = app.nsuboptEditField.Value;            
        end

        % Value changed function: nsubmaxEditField
        function nsubmaxEditFieldValueChanged(app, event)
            app.smax = app.nsubmaxEditField.Value;
        end

        % Value changed function: MaximumRelativeErrorEditField
        function MaximumRelativeErrorEditFieldValueChanged(app, event)
            app.max_err = app.MaximumRelativeErrorEditField.Value;
        end

        % Value changed function: LowerFrequencyradsEditField
        function LowerFrequencyradsEditFieldValueChanged(app, event)
            app.w_low = app.LowerFrequencyradsEditField.Value;
        end

        % Value changed function: UpperFrequencyradsEditField
        function UpperFrequencyradsEditFieldValueChanged(app, event)
            app.w_up = app.UpperFrequencyradsEditField.Value;
        end

        % Callback function
        function StopButtonPushed(app, event)
            app.stop_sim=1;
        end

        % Button pushed function: writetofileButton
        function writetofileButtonPushed(app, event)
            file_name=app.filenameEditField.Value;
            write_linear_data(app.n,app.K,app.M,app.C,app.gama,app.f,file_name);
        end

        % Button pushed function: writetofileButton_2
        function writetofileButton_2Pushed(app, event)
            file_name=app.filenameEditField_2.Value;
            writematrix(app.NL_data,file_name);
        end

        % Button pushed function: GotoPostProcessorButton
        function GotoPostProcessorButtonPushed(app, event)
            %convert from modal coordinates to actual coordinates
            app.Res_conv=[app.Phi_s*app.Res(1:end-1,:);app.Res(end,:)];
            app.post_processor=post_processor(app,app.Res_conv);
        end

        % Button pushed function: WriteResultstofileButton
        function WriteResultstofileButtonPushed(app, event)
            file_name=app.filenameEditField_3.Value;
            app.Res_conv=[app.Phi_s*app.Res(1:end-1,:);app.Res(end,:)];
            writematrix(app.Res_conv,file_name);
        end

        % Value changed function: DOFEditField
        function DOFEditFieldValueChanged(app, event)
            app.n=app.DOFEditField.Value;
            app.C=zeros(app.n,app.n);
        end

        % Button pushed function: KButton
        function KButtonPushed(app, event)
            app.K_app=K_app(app,app.n);
        end

        % Button pushed function: MButton
        function MButtonPushed(app, event)
            app.M_app=M_app(app,app.n);
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.C_app=C_app(app,app.n);
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
            app.F_app=F_app(app,app.n);
        end

        % Value changed function: LossFactorEditField
        function LossFactorEditFieldValueChanged(app, event)
            value = app.LossFactorEditField.Value;
            if value==0;
                app.gama=0.0001;
            else
                app.gama=value;
            end
        end

        % Button pushed function: OpeneditorButton
        function OpeneditorButtonPushed(app, event)
            app.NL_editor=NL_editor(app,app.NL_data);
        end

        % Callback function
        function ResetallButtonPushed(app, event)
            startupFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LinearSystemParametersPanel = uipanel(app.UIFigure);
            app.LinearSystemParametersPanel.Title = 'Linear System Parameters';
            app.ButtonGroup = uibuttongroup(app.LinearSystemParametersPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.radiobutton1 = uiradiobutton(app.ButtonGroup);
            app.radiobutton1.Text = 'Read all the data from file';
            app.radiobutton1.Value = true;
            app.radiobutton2 = uiradiobutton(app.ButtonGroup);
            app.radiobutton2.Text = 'Enter matrices seperately';
            app.writetofileButton = uibutton(app.LinearSystemParametersPanel, 'push');
            app.writetofileButton.ButtonPushedFcn = createCallbackFcn(app, @writetofileButtonPushed, true);
            app.writetofileButton.Enable = 'off';
            app.writetofileButton.Text = 'write to file';
            app.filenameEditFieldLabel = uilabel(app.LinearSystemParametersPanel);
            app.filenameEditFieldLabel.Text = 'file name:';
            app.filenameEditField = uieditfield(app.LinearSystemParametersPanel, 'text');
            app.filenameEditField.Value = 'linear_data1.txt';
            app.SelectfileButton = uibutton(app.LinearSystemParametersPanel, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.ProceedButton = uibutton(app.LinearSystemParametersPanel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Enable = 'off';
            app.ProceedButton.Text = 'Proceed';
            app.Panel = uipanel(app.LinearSystemParametersPanel);
            app.Panel.Enable = 'off';
            app.LossFactorEditFieldLabel = uilabel(app.Panel);
            app.LossFactorEditFieldLabel.Text = 'Loss Factor';
            app.LossFactorEditField = uieditfield(app.Panel, 'numeric');
            app.LossFactorEditField.ValueChangedFcn = createCallbackFcn(app, @LossFactorEditFieldValueChanged, true);
            app.LossFactorEditField.Value = 0.01;
            app.DOFEditFieldLabel = uilabel(app.Panel);
            app.DOFEditFieldLabel.Text = 'DOF';
            app.DOFEditField = uieditfield(app.Panel, 'numeric');
            app.DOFEditField.ValueChangedFcn = createCallbackFcn(app, @DOFEditFieldValueChanged, true);
            app.DOFEditField.Value = 1;
            app.KButton = uibutton(app.Panel, 'push');
            app.KButton.ButtonPushedFcn = createCallbackFcn(app, @KButtonPushed, true);
            app.KButton.Text = 'K';
            app.MButton = uibutton(app.Panel, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.MButton.Enable = 'off';
            app.MButton.Text = 'M';
            app.CButton = uibutton(app.Panel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.FButton = uibutton(app.Panel, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Enable = 'off';
            app.FButton.Text = 'F';
            app.displayButton = uibutton(app.UIFigure, 'push');
            app.displayButton.ButtonPushedFcn = createCallbackFcn(app, @displayButtonPushed, true);
            app.displayButton.Text = 'display';
            app.ModalAnalysisPanel = uipanel(app.UIFigure);
            app.ModalAnalysisPanel.Enable = 'off';
            app.ModalAnalysisPanel.Title = 'Modal Analysis';
            app.NumberofmodestocalculateEditFieldLabel = uilabel(app.ModalAnalysisPanel);
            app.NumberofmodestocalculateEditFieldLabel.Text = 'Number of modes to calculate';
            app.modestocalculateE_F = uieditfield(app.ModalAnalysisPanel, 'numeric');
            app.modestocalculateE_F.ValueChangedFcn = createCallbackFcn(app, @modestocalculateE_FValueChanged, true);
            app.modestocalculateE_F.Value = 1;
            app.NaturalFrequenciesTextAreaLabel = uilabel(app.ModalAnalysisPanel);
            app.NaturalFrequenciesTextAreaLabel.Text = 'Natural Frequencies';
            app.wn_s_T_A = uitextarea(app.ModalAnalysisPanel);
            app.NumberofmodestokeepEditFieldLabel = uilabel(app.ModalAnalysisPanel);
            app.NumberofmodestokeepEditFieldLabel.Text = 'Number of modes to keep';
            app.modestokeepE_F = uieditfield(app.ModalAnalysisPanel, 'numeric');
            app.modestokeepE_F.ValueChangedFcn = createCallbackFcn(app, @modestokeepE_FValueChanged, true);
            app.modestokeepE_F.Value = 1;
            app.CalculateButton = uibutton(app.ModalAnalysisPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ProceedButton_2 = uibutton(app.ModalAnalysisPanel, 'push');
            app.ProceedButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProceedButton_2Pushed, true);
            app.ProceedButton_2.Enable = 'off';
            app.ProceedButton_2.Text = 'Proceed';
            app.AddNonlinearitiesPanel = uipanel(app.UIFigure);
            app.AddNonlinearitiesPanel.Enable = 'off';
            app.AddNonlinearitiesPanel.Title = 'Add Nonlinearities';
            app.ProceedButton_3 = uibutton(app.AddNonlinearitiesPanel, 'push');
            app.ProceedButton_3.ButtonPushedFcn = createCallbackFcn(app, @ProceedButton_3Pushed, true);
            app.ProceedButton_3.Enable = 'off';
            app.ProceedButton_3.Text = 'Proceed';
            app.writetofileButton_2 = uibutton(app.AddNonlinearitiesPanel, 'push');
            app.writetofileButton_2.ButtonPushedFcn = createCallbackFcn(app, @writetofileButton_2Pushed, true);
            app.writetofileButton_2.Enable = 'off';
            app.writetofileButton_2.Text = 'write to file';
            app.filenameEditField_2Label = uilabel(app.AddNonlinearitiesPanel);
            app.filenameEditField_2Label.Text = 'file name:';
            app.filenameEditField_2 = uieditfield(app.AddNonlinearitiesPanel, 'text');
            app.filenameEditField_2.Value = 'NL_data1.txt';
            app.SelectfileButton_2 = uibutton(app.AddNonlinearitiesPanel, 'push');
            app.SelectfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButton_2Pushed, true);
            app.SelectfileButton_2.Text = 'Select file';
            app.OpeneditorButton = uibutton(app.AddNonlinearitiesPanel, 'push');
            app.OpeneditorButton.ButtonPushedFcn = createCallbackFcn(app, @OpeneditorButtonPushed, true);
            app.OpeneditorButton.Enable = 'off';
            app.OpeneditorButton.Text = 'Open editor';
            app.ButtonGroup_2 = uibuttongroup(app.AddNonlinearitiesPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.radiobutton3 = uiradiobutton(app.ButtonGroup_2);
            app.radiobutton3.Text = 'Read from file';
            app.radiobutton3.Value = true;
            app.radiobutton4 = uiradiobutton(app.ButtonGroup_2);
            app.radiobutton4.Text = 'Add manually';
            app.SolutionParametersPanel = uipanel(app.UIFigure);
            app.SolutionParametersPanel.Title = 'Solution Parameters';
            app.ssubminEditFieldLabel = uilabel(app.SolutionParametersPanel);
            app.ssubminEditFieldLabel.Interpreter = 'html';
            app.ssubminEditFieldLabel.Text = 's<sub>min';
            app.ssubminEditField = uieditfield(app.SolutionParametersPanel, 'numeric');
            app.ssubminEditField.ValueChangedFcn = createCallbackFcn(app, @ssubminEditFieldValueChanged, true);
            app.ssubminEditField.Value = 0.0001;
            app.ssubmaxEditFieldLabel = uilabel(app.SolutionParametersPanel);
            app.ssubmaxEditFieldLabel.Interpreter = 'html';
            app.ssubmaxEditFieldLabel.Text = 's<sub>max';
            app.ssubmaxEditField = uieditfield(app.SolutionParametersPanel, 'numeric');
            app.ssubmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ssubmaxEditFieldValueChanged, true);
            app.ssubmaxEditField.Value = 0.01;
            app.SolveButton = uibutton(app.SolutionParametersPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Enable = 'off';
            app.SolveButton.Text = 'Solve';
            app.nsuboptEditFieldLabel = uilabel(app.SolutionParametersPanel);
            app.nsuboptEditFieldLabel.Interpreter = 'html';
            app.nsuboptEditFieldLabel.Text = 'n<sub>opt';
            app.nsuboptEditField = uieditfield(app.SolutionParametersPanel, 'numeric');
            app.nsuboptEditField.ValueChangedFcn = createCallbackFcn(app, @nsuboptEditFieldValueChanged, true);
            app.nsuboptEditField.Value = 3;
            app.nsubmaxEditFieldLabel = uilabel(app.SolutionParametersPanel);
            app.nsubmaxEditFieldLabel.Interpreter = 'html';
            app.nsubmaxEditFieldLabel.Text = 'n<sub>max';
            app.nsubmaxEditField = uieditfield(app.SolutionParametersPanel, 'numeric');
            app.nsubmaxEditField.ValueChangedFcn = createCallbackFcn(app, @nsubmaxEditFieldValueChanged, true);
            app.nsubmaxEditField.Value = 20;
            app.MaximumRelativeErrorEditFieldLabel = uilabel(app.SolutionParametersPanel);
            app.MaximumRelativeErrorEditFieldLabel.Interpreter = 'html';
            app.MaximumRelativeErrorEditFieldLabel.Text = 'Maximum Relative Error';
            app.MaximumRelativeErrorEditField = uieditfield(app.SolutionParametersPanel, 'numeric');
            app.MaximumRelativeErrorEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumRelativeErrorEditFieldValueChanged, true);
            app.MaximumRelativeErrorEditField.Value = 1e-08;
            app.LowerFrequencyradsEditFieldLabel = uilabel(app.SolutionParametersPanel);
            app.LowerFrequencyradsEditFieldLabel.Interpreter = 'html';
            app.LowerFrequencyradsEditFieldLabel.Text = 'Lower Frequency (rad/s)';
            app.LowerFrequencyradsEditField = uieditfield(app.SolutionParametersPanel, 'numeric');
            app.LowerFrequencyradsEditField.ValueChangedFcn = createCallbackFcn(app, @LowerFrequencyradsEditFieldValueChanged, true);
            app.LowerFrequencyradsEditField.Value = 10;
            app.UpperFrequencyradsEditFieldLabel = uilabel(app.SolutionParametersPanel);
            app.UpperFrequencyradsEditFieldLabel.Interpreter = 'html';
            app.UpperFrequencyradsEditFieldLabel.Text = 'Upper Frequency (rad/s)';
            app.UpperFrequencyradsEditField = uieditfield(app.SolutionParametersPanel, 'numeric');
            app.UpperFrequencyradsEditField.ValueChangedFcn = createCallbackFcn(app, @UpperFrequencyradsEditFieldValueChanged, true);
            app.UpperFrequencyradsEditField.Value = 50;
            app.GotoPostProcessorButton = uibutton(app.UIFigure, 'push');
            app.GotoPostProcessorButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPostProcessorButtonPushed, true);
            app.GotoPostProcessorButton.Text = 'Go to Post Processor';
            app.WriteResultstofileButton = uibutton(app.UIFigure, 'push');
            app.WriteResultstofileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteResultstofileButtonPushed, true);
            app.WriteResultstofileButton.Text = 'Write Results to file';
            app.filenameEditField_3Label = uilabel(app.UIFigure);
            app.filenameEditField_3Label.Text = 'file name:';
            app.filenameEditField_3 = uieditfield(app.UIFigure, 'text');
            app.filenameEditField_3.Value = 'Results.txt';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = me579

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end